classdef heat_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        PlotButton                  matlab.ui.control.Button
        WavespeedmsEditField        matlab.ui.control.NumericEditField
        WavespeedmsEditFieldLabel   matlab.ui.control.Label
        NumberoftimestepsEditField  matlab.ui.control.NumericEditField
        NumberoftimestepsEditFieldLabel  matlab.ui.control.Label
        NumberofspatialgridpointsEditField  matlab.ui.control.NumericEditField
        NumberofspatialgridpointsEditFieldLabel  matlab.ui.control.Label
        TotalTimeEditField          matlab.ui.control.NumericEditField
        TotalTimeEditFieldLabel     matlab.ui.control.Label
        LengthEditField             matlab.ui.control.NumericEditField
        LengthEditFieldLabel        matlab.ui.control.Label
        HeatLabel                   matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            assignin('base', 'l_Val2', app.LengthEditField.Value)
            assignin('base', 't_Val2', app.TotalTimeEditField.Value)
            assignin('base', 'nx_Val2', app.NumberofspatialgridpointsEditField.Value)
            assignin('base', 'nt_Val2', app.NumberoftimestepsEditField.Value)
            assignin('base', 'alpha_Val', app.WavespeedmsEditField.Value)
            app.delete
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.HeatLabel = uilabel(app.UIFigure);
            app.HeatLabel.Text = 'Heat';
            app.LengthEditFieldLabel = uilabel(app.UIFigure);
            app.LengthEditFieldLabel.Text = 'Length';
            app.LengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.TotalTimeEditFieldLabel = uilabel(app.UIFigure);
            app.TotalTimeEditFieldLabel.Text = 'Total Time';
            app.TotalTimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofspatialgridpointsEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofspatialgridpointsEditFieldLabel.Text = 'Number of spatial grid points';
            app.NumberofspatialgridpointsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberoftimestepsEditFieldLabel = uilabel(app.UIFigure);
            app.NumberoftimestepsEditFieldLabel.Text = 'Number of time steps';
            app.NumberoftimestepsEditField = uieditfield(app.UIFigure, 'numeric');
            app.WavespeedmsEditFieldLabel = uilabel(app.UIFigure);
            app.WavespeedmsEditFieldLabel.Text = 'Wave speed (m/s)';
            app.WavespeedmsEditField = uieditfield(app.UIFigure, 'numeric');
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = heat_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end